classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReconocimientodeVocalesUIFigure  matlab.ui.Figure
        PROCESAMIENTODEVOZPanel  matlab.ui.container.Panel
        Label                    matlab.ui.control.Label
        lblgrabar                matlab.ui.control.Lamp
        REPRODUCIRButton         matlab.ui.control.Button
        grafVozGrabada           matlab.ui.control.UIAxes
        COMPARARVOZCONBDButton   matlab.ui.control.Button
        grafVozTratada           matlab.ui.control.UIAxes
        grafVozEncontrada        matlab.ui.control.UIAxes
        GrabarLabel              matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        RECONOMIENTODEVOZPanel   matlab.ui.container.Panel
        RECONOCERButton          matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        RespuestaEditFieldLabel  matlab.ui.control.Label
        txtRespuesta             matlab.ui.control.EditField
        grafImagen               matlab.ui.control.UIAxes
        grafLogo                 matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function Iniciar(app)
            app.grafLogo.XAxis.TickLabels = {};
            app.grafLogo.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow('Logo.jpg', 'Parent', app.grafLogo,'XData', [1 app.grafLogo.Position(3)],'YData', [1 app.grafLogo.Position(4)]);
            app.grafLogo.XLim = [0 I.XData(2)];
            app.grafLogo.YLim = [0 I.YData(2)];
        end

        % Button pushed function: RECONOCERButton
        function RECONOCERButtonPushed(app, event)
            % ENCENDER LAMPARA 
            app.lblgrabar.Color= 'red';
            app.GrabarLabel.Text="Grabando";
            app.txtRespuesta.Value="";
            [rta, voice_x, voice_PX, voice_BD]=evaluarVoice;
            app.txtRespuesta.Value=rta;
            app.GrabarLabel.Text="Grabar";
            app.lblgrabar.Color= 'green';
            plot(app.grafVozGrabada, voice_x);
            plot(app.grafVozTratada, voice_PX);
            if(voice_BD~=0)
                plot(app.grafVozEncontrada, voice_BD);
            end
            global voiceGrabada;
            voiceGrabada=voice_x;
      
            app.grafImagen.XAxis.TickLabels = {};
            app.grafImagen.YAxis.TickLabels = {};
             % Display image and stretch to fill axes
            if(rta=="Vocal A") 
            I = imshow('A.jpg', 'Parent', app.grafImagen,'XData', [1 app.grafImagen.Position(3)],'YData', [1 app.grafImagen.Position(4)]);
            end
            if(rta=="Vocal E") 
            I = imshow('E.jpg', 'Parent', app.grafImagen,'XData', [1 app.grafImagen.Position(3)],'YData', [1 app.grafImagen.Position(4)]);
            end
            if(rta=="Vocal I") 
            I = imshow('I.jpg', 'Parent', app.grafImagen,'XData', [1 app.grafImagen.Position(3)],'YData', [1 app.grafImagen.Position(4)]);
            end
            if(rta=="Vocal O") 
            I = imshow('O.jpg', 'Parent', app.grafImagen,'XData', [1 app.grafImagen.Position(3)],'YData', [1 app.grafImagen.Position(4)]);
            end
            if(rta=="Vocal U") 
            I = imshow('U.jpg', 'Parent', app.grafImagen,'XData', [1 app.grafImagen.Position(3)],'YData', [1 app.grafImagen.Position(4)]);
            end
            if(rta=="error vocal no identificada") 
            I = imshow('Desconocido.png', 'Parent', app.grafImagen,'XData', [1 app.grafImagen.Position(3)],'YData', [1 app.grafImagen.Position(4)]);
            end
            % Set limits of axes
            app.grafImagen.XLim = [0 I.XData(2)];
            app.grafImagen.YLim = [0 I.YData(2)];
        end

        % Button pushed function: REPRODUCIRButton
        function Reproducir(app, event)
            global voiceGrabada;
            sound(voiceGrabada, 16000);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReconocimientodeVocalesUIFigure = uifigure;
            app.ReconocimientodeVocalesUIFigure.Name = 'Reconocimiento de Vocales';
            app.PROCESAMIENTODEVOZPanel = uipanel(app.ReconocimientodeVocalesUIFigure);
            app.PROCESAMIENTODEVOZPanel.Title = '   PROCESAMIENTO DE VOZ';
            app.Label = uilabel(app.PROCESAMIENTODEVOZPanel);
            app.Label.Text = '';
            app.lblgrabar = uilamp(app.PROCESAMIENTODEVOZPanel);
            app.REPRODUCIRButton = uibutton(app.PROCESAMIENTODEVOZPanel, 'push');
            app.REPRODUCIRButton.ButtonPushedFcn = createCallbackFcn(app, @Reproducir, true);
            app.REPRODUCIRButton.Text = 'REPRODUCIR';
            app.grafVozGrabada = uiaxes(app.PROCESAMIENTODEVOZPanel);
            title(app.grafVozGrabada, 'Voz Grabada')
            xlabel(app.grafVozGrabada, 'Tiempo')
            ylabel(app.grafVozGrabada, 'Amplitud')
            app.COMPARARVOZCONBDButton = uibutton(app.PROCESAMIENTODEVOZPanel, 'push');
            app.COMPARARVOZCONBDButton.Text = 'COMPARAR VOZ CON BD';
            app.grafVozTratada = uiaxes(app.PROCESAMIENTODEVOZPanel);
            title(app.grafVozTratada, 'Voz Tratada')
            xlabel(app.grafVozTratada, 'Tiempo')
            ylabel(app.grafVozTratada, 'Amplitud')
            app.grafVozEncontrada = uiaxes(app.PROCESAMIENTODEVOZPanel);
            title(app.grafVozEncontrada, 'Voz Encontrada')
            xlabel(app.grafVozEncontrada, 'Tiempo')
            ylabel(app.grafVozEncontrada, 'Amplitud')
            app.GrabarLabel = uilabel(app.PROCESAMIENTODEVOZPanel);
            app.GrabarLabel.Text = 'Grabar';
            app.TextArea = uitextarea(app.ReconocimientodeVocalesUIFigure);
            app.TextArea.Value = {'UNIVERSIDAD POPULAR DEL CESAR'; 'VALLEDUPAR-CESAR'; '2020'};
            app.RECONOMIENTODEVOZPanel = uipanel(app.ReconocimientodeVocalesUIFigure);
            app.RECONOMIENTODEVOZPanel.Title = 'RECONOMIENTO DE VOZ';
            app.RECONOCERButton = uibutton(app.RECONOMIENTODEVOZPanel, 'push');
            app.RECONOCERButton.ButtonPushedFcn = createCallbackFcn(app, @RECONOCERButtonPushed, true);
            app.RECONOCERButton.Text = 'RECONOCER';
            app.EditField = uieditfield(app.RECONOMIENTODEVOZPanel, 'text');
            app.RespuestaEditFieldLabel = uilabel(app.ReconocimientodeVocalesUIFigure);
            app.RespuestaEditFieldLabel.Text = 'Respuesta';
            app.txtRespuesta = uieditfield(app.ReconocimientodeVocalesUIFigure, 'text');
            app.grafImagen = uiaxes(app.ReconocimientodeVocalesUIFigure);
            title(app.grafImagen, 'Vocal')
            app.grafLogo = uiaxes(app.ReconocimientodeVocalesUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReconocimientodeVocalesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Iniciar)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReconocimientodeVocalesUIFigure)
        end
    end
end